classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        Lamp_6                     matlab.ui.control.Lamp
        Gauge_8                    matlab.ui.control.NinetyDegreeGauge
        Gauge_8Label               matlab.ui.control.Label
        Gauge_7                    matlab.ui.control.NinetyDegreeGauge
        Gauge_7Label               matlab.ui.control.Label
        Gauge_6                    matlab.ui.control.NinetyDegreeGauge
        Gauge_6Label               matlab.ui.control.Label
        Gauge_5                    matlab.ui.control.NinetyDegreeGauge
        Gauge_5Label               matlab.ui.control.Label
        Cell4Switch                matlab.ui.control.RockerSwitch
        Cell4SwitchLabel           matlab.ui.control.Label
        Cell3Switch                matlab.ui.control.RockerSwitch
        Cell3SwitchLabel           matlab.ui.control.Label
        Cell2Switch                matlab.ui.control.RockerSwitch
        Cell2SwitchLabel           matlab.ui.control.Label
        Cell1Switch                matlab.ui.control.RockerSwitch
        Cell1SwitchLabel           matlab.ui.control.Label
        ComDropDown                matlab.ui.control.DropDown
        ComDropDownLabel           matlab.ui.control.Label
        Lamp_8                     matlab.ui.control.Lamp
        Lamp_7                     matlab.ui.control.Lamp
        Lamp_5                     matlab.ui.control.Lamp
        BatteryProfileButtonGroup  matlab.ui.container.ButtonGroup
        Button3                    matlab.ui.control.RadioButton
        LeadAcidButton             matlab.ui.control.RadioButton
        CustomButton               matlab.ui.control.RadioButton
        Lamp_4                     matlab.ui.control.Lamp
        Lamp_3                     matlab.ui.control.Lamp
        Lamp_2                     matlab.ui.control.Lamp
        Lamp                       matlab.ui.control.Lamp
        ConnButton                 matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        ApplyButton                matlab.ui.control.Button
        SetVoltEditField           matlab.ui.control.NumericEditField
        SetVoltEditFieldLabel      matlab.ui.control.Label
        SetCurrentEditField        matlab.ui.control.NumericEditField
        SetCurrentEditFieldLabel   matlab.ui.control.Label
        CurrentAKnob               matlab.ui.control.Knob
        CurrentAKnobLabel          matlab.ui.control.Label
        VoltVKnob                  matlab.ui.control.Knob
        VoltVKnobLabel             matlab.ui.control.Label
        VoltVGauge                 matlab.ui.control.LinearGauge
        VoltVGaugeLabel            matlab.ui.control.Label
        CurrentAGauge              matlab.ui.control.LinearGauge
        CurrentAGaugeLabel         matlab.ui.control.Label
        CellselectDropDown         matlab.ui.control.DropDown
        CellselectDropDownLabel    matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        %   I put all global(app) variables here
        PortCOM % String for showing com
        serialObj % Actual serial object
        ActiveCell  %   Select Active cell
        %   OUTPUT scalar
        Vscale = 48 %   1/243
        Iscale = 20 %   1/50
        %   Variables to store Volt
        va, vb, vc, vd
        %   Variables to store current
        ca, cb, cc, cd
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PortCOM = serialportlist("available"); % Scan for available ports
            app.ComDropDown.Items = app.PortCOM; % Update dropdown items
            app.va=0; app.vb=0; app.vc=0; app.vd=0; %   Make sure values start as 0
            app.ca=0; app.cb=0; app.cc=0; app.cd=0; %   Make sure values start at 0
        end

        % Button pushed function: ConnButton
        function ConnButtonPushed(app, event)
            value = app.ComDropDown.ValueIndex;
            %   Change to something that app doesnt crash when serial is
            %   not avaiable.
            try
                app.serialObj = serialport(app.PortCOM(value), 115200); % Connect to the first available port
                configureTerminator(app.serialObj, "CR/LF");  % or "LF", "CR/LF", or a custom string
                disp('Connected to the ESP32');
            catch ME
                disp(['Error connecting to ESP32: ', ME.message]);
                disp(value);
                %   Add something to try again
            end
        end

        % Value changed function: Cell1Switch
        function Cell1SwitchValueChanged(app, event)
            command = app.Cell1Switch.Value;
            str = num2str(command); % Convert to string
            writeline(app.serialObj, "L" + str); %  L is semantic coresponding with RGBW
            disp(str)
        end

        % Value changed function: CellselectDropDown
        function CellselectDropDownValueChanged(app, event)
            app.ActiveCell = app.CellselectDropDown.Value;
            disp(app.ActiveCell);
        end

        % Value changed function: VoltVKnob
        function VoltVKnobValueChanged(app, event)
            value = app.VoltVKnob.Value;
            switch(app.ActiveCell)
                case 'A'
                    app.va = value;
                case 'B'
                    app.vb = value;
                case 'C'
                    app.vc = value;
                case 'D'
                    app.vd = value;
                case 'ALL'
                    app.va = value;
                    app.vb = value;
                    app.vd = value;
                    app.vc = value;
                    disp(value);
                otherwise
                    disp("No cell selected!")
            end  
        end

        % Value changed function: CurrentAKnob
        function CurrentAKnobValueChanged(app, event)
            value = app.CurrentAKnob.Value;
            switch(app.ActiveCell)
                case 'A'
                    app.ca=value;
                case 'B'
                    app.cb=value;
                case 'C'
                    app.cc=value;
                case 'D'
                    app.cd=value;
                case 'ALL'
                    app.ca=value;
                    app.cb=value;
                    app.cc=value;
                    app.cd=value;
                    disp(value);
                otherwise
                    disp("No cell selected!")
            end
        end

        % Button pushed function: ApplyButton
        function ApplyButtonPushed(app, event)
            %   Print values with scaling applied
            %   writeline(app.serialObj, "va" + num2str((app.Vscale*app.va),0) + "ca" + num2str((app.Iscale*app.ca),0) + "vb" + num2str((app.Vscale*app.vb),0) + "cb" + num2str((app.Iscale*app.cb),0));  %   CHANGE TO CONVERT TO CORRECT VALUE
            outputString = "va" + num2str((app.Vscale * app.va), 3) + "ca" + num2str((app.Iscale * app.ca), 2) + "vb" + num2str((app.Vscale * app.vb), 3) + "cb" + num2str((app.Iscale * app.cb), 2)+ "vc" + num2str((app.Vscale * app.vc), 3) + "cc" + num2str((app.Iscale * app.cc), 2)+ "vd" + num2str((app.Vscale * app.vd), 3) + "cd" + num2str((app.Iscale * app.cd), 2);
            writeline(app.serialObj, outputString);  % Send to serial connection
            disp(outputString);                       % Print to MATLAB console
        end

        % Value changed function: SetVoltEditField
        function SetVoltEditFieldValueChanged(app, event)
            value = app.SetVoltEditField.Value;
            switch(app.ActiveCell)
                case 'A'
                    app.va = value;
                case 'B'
                    app.vb = value;
                case 'C'
                    app.vc = value;
                case 'D'
                    app.vd = value;
                case 'ALL'
                    app.va = value;
                    app.vb = value;
                    app.vd = value;
                    app.vc = value;
                    disp(value);
                otherwise
                    disp("No cell selected!")
            end  
        end

        % Value changed function: SetCurrentEditField
        function SetCurrentEditFieldValueChanged(app, event)
            value = app.SetCurrentEditField.Value;
            switch(app.ActiveCell)
                case 'A'
                    app.ca=value;
                case 'B'
                    app.cb=value;
                case 'C'
                    app.cc=value;
                case 'D'
                    app.cd=value;
                case 'ALL'
                    app.ca=value;
                    app.cb=value;
                    app.cc=value;
                    app.cd=value;
                    disp(value);
                otherwise
                    disp("No cell selected!")
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.LeftPanel);
            app.ConnButton = uibutton(app.GridLayout3, 'push');
            app.ConnButton.ButtonPushedFcn = createCallbackFcn(app, @ConnButtonPushed, true);
            app.ConnButton.Text = 'Conn';
            app.Lamp = uilamp(app.GridLayout3);
            app.Lamp_2 = uilamp(app.GridLayout3);
            app.Lamp_3 = uilamp(app.GridLayout3);
            app.Lamp_4 = uilamp(app.GridLayout3);
            app.BatteryProfileButtonGroup = uibuttongroup(app.GridLayout3);
            app.BatteryProfileButtonGroup.Title = 'Battery Profile';
            app.CustomButton = uiradiobutton(app.BatteryProfileButtonGroup);
            app.CustomButton.Text = 'Custom';
            app.CustomButton.Value = true;
            app.LeadAcidButton = uiradiobutton(app.BatteryProfileButtonGroup);
            app.LeadAcidButton.Text = 'LeadAcid';
            app.Button3 = uiradiobutton(app.BatteryProfileButtonGroup);
            app.Button3.Text = 'Button3';
            app.Lamp_5 = uilamp(app.GridLayout3);
            app.Lamp_7 = uilamp(app.GridLayout3);
            app.Lamp_8 = uilamp(app.GridLayout3);
            app.ComDropDownLabel = uilabel(app.GridLayout3);
            app.ComDropDownLabel.Text = 'Com';
            app.ComDropDown = uidropdown(app.GridLayout3);
            app.Cell1SwitchLabel = uilabel(app.GridLayout3);
            app.Cell1SwitchLabel.Text = 'Cell1';
            app.Cell1Switch = uiswitch(app.GridLayout3, 'rocker');
            app.Cell1Switch.ValueChangedFcn = createCallbackFcn(app, @Cell1SwitchValueChanged, true);
            app.Cell2SwitchLabel = uilabel(app.GridLayout3);
            app.Cell2SwitchLabel.Text = 'Cell2';
            app.Cell2Switch = uiswitch(app.GridLayout3, 'rocker');
            app.Cell3SwitchLabel = uilabel(app.GridLayout3);
            app.Cell3SwitchLabel.Text = 'Cell3';
            app.Cell3Switch = uiswitch(app.GridLayout3, 'rocker');
            app.Cell4SwitchLabel = uilabel(app.GridLayout3);
            app.Cell4SwitchLabel.Text = 'Cell4';
            app.Cell4Switch = uiswitch(app.GridLayout3, 'rocker');
            app.Gauge_5Label = uilabel(app.GridLayout3);
            app.Gauge_5Label.Text = 'Gauge';
            app.Gauge_5 = uigauge(app.GridLayout3, 'ninetydegree');
            app.Gauge_6Label = uilabel(app.GridLayout3);
            app.Gauge_6Label.Text = 'Gauge';
            app.Gauge_6 = uigauge(app.GridLayout3, 'ninetydegree');
            app.Gauge_7Label = uilabel(app.GridLayout3);
            app.Gauge_7Label.Text = 'Gauge';
            app.Gauge_7 = uigauge(app.GridLayout3, 'ninetydegree');
            app.Gauge_8Label = uilabel(app.GridLayout3);
            app.Gauge_8Label.Text = 'Gauge';
            app.Gauge_8 = uigauge(app.GridLayout3, 'ninetydegree');
            app.Lamp_6 = uilamp(app.GridLayout3);
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.RightPanel);
            app.CellselectDropDownLabel = uilabel(app.GridLayout2);
            app.CellselectDropDownLabel.Text = 'Cell select';
            app.CellselectDropDown = uidropdown(app.GridLayout2);
            app.CellselectDropDown.Items = {'-', 'A', 'B', 'C', 'D', 'ALL'};
            app.CellselectDropDown.ValueChangedFcn = createCallbackFcn(app, @CellselectDropDownValueChanged, true);
            app.CellselectDropDown.Value = '-';
            app.CurrentAGaugeLabel = uilabel(app.GridLayout2);
            app.CurrentAGaugeLabel.Text = 'Current(A)';
            app.CurrentAGauge = uigauge(app.GridLayout2, 'linear');
            app.CurrentAGauge.Limits = [0 2.5];
            app.VoltVGaugeLabel = uilabel(app.GridLayout2);
            app.VoltVGaugeLabel.Text = 'Volt(V)';
            app.VoltVGauge = uigauge(app.GridLayout2, 'linear');
            app.VoltVGauge.Limits = [0 5];
            app.VoltVKnobLabel = uilabel(app.GridLayout2);
            app.VoltVKnobLabel.Text = 'Volt(V)';
            app.VoltVKnob = uiknob(app.GridLayout2, 'continuous');
            app.VoltVKnob.Limits = [1 5];
            app.VoltVKnob.ValueChangedFcn = createCallbackFcn(app, @VoltVKnobValueChanged, true);
            app.VoltVKnob.Value = 1;
            app.CurrentAKnobLabel = uilabel(app.GridLayout2);
            app.CurrentAKnobLabel.Text = 'Current(A)';
            app.CurrentAKnob = uiknob(app.GridLayout2, 'continuous');
            app.CurrentAKnob.Limits = [0 2];
            app.CurrentAKnob.ValueChangedFcn = createCallbackFcn(app, @CurrentAKnobValueChanged, true);
            app.SetCurrentEditFieldLabel = uilabel(app.GridLayout2);
            app.SetCurrentEditFieldLabel.Text = 'Set  Current';
            app.SetCurrentEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SetCurrentEditField.ValueChangedFcn = createCallbackFcn(app, @SetCurrentEditFieldValueChanged, true);
            app.SetVoltEditFieldLabel = uilabel(app.GridLayout2);
            app.SetVoltEditFieldLabel.Text = 'Set Volt';
            app.SetVoltEditField = uieditfield(app.GridLayout2, 'numeric');
            app.SetVoltEditField.ValueChangedFcn = createCallbackFcn(app, @SetVoltEditFieldValueChanged, true);
            app.ApplyButton = uibutton(app.GridLayout2, 'push');
            app.ApplyButton.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.ApplyButton.Text = 'Apply';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end